classdef app1 < matlab.apps.AppBase
    %APP1
    %   This app using CNN model to detect derecho day using open image
    %   source. This is a beta form of the application and development in
    %   progess.

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Image2                         matlab.ui.control.Image
        RightPanel                     matlab.ui.container.Panel
        ResultEditField                matlab.ui.control.EditField
        ResultEditFieldLabel           matlab.ui.control.Label
        PredictButton                  matlab.ui.control.Button
        LoadImageButton                matlab.ui.control.Button
        ModelforDerechoDetectionLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    methods (Access = private)
      
function updateimage(app,imagefile)
            
            % For corn.tif, read the second image in the file
            global im;
              im = imread(imagefile);
              im = imresize(im,[224 224]);
              imagesc(app.UIAxes,im)

                end            
            % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.UIAxes.Visible = 'off';
            app.UIAxes.Colormap = gray(256);
            axis(app.UIAxes, 'image'); 
        end
    end
    
    methods (Access = public)


% Create Predict
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: LoadImageButton, UIAxes
        function LoadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;'};
            [f, p] = uigetfile(filterspec);

            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);

            end

            f = string(f);
            f = strsplit(f,'_');
            f = f(end);
            app.UIAxes.Title.String = f;


        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
           global im;
            model=load("resnet_50_results.mat");
            %app.net = app.net.netTransfer);
            [YPred]= classify(model.netTransfer,im);
            s=string(YPred);
            result = string(YPred);
            app.ResultEditField.Value = sprintf('It is a %s day',result);

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'KCC_business card logo.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.RightPanel.ButtonDownFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.ModelforDerechoDetectionLabel = uilabel(app.RightPanel);
            app.ModelforDerechoDetectionLabel.Text = 'Model for Derecho Detection';
            app.LoadImageButton = uibutton(app.RightPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image ';
            app.PredictButton = uibutton(app.RightPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.ResultEditFieldLabel = uilabel(app.RightPanel);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.RightPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end